classdef k2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Image2    matlab.ui.control.Image
    end

    
    methods (Access = private)
        function startupFcn(app)
        img33 = imread('k15.png'); 
        app.Image2.ImageSource = img33;
        end
       function decoded = irle(app,input)
            decoded = [];
            for i = 1:2:length(input)
                symbol = input(i);
                count = input(i+1);
                decoded = [decoded, repmat(symbol, 1, count)];
            end
        end

        function Output=rle(app,Input)
            L=length(Input);
            j=1;
            k=1;
            i=1;
            while i<2*L
                comp=1;
                for j=j:L
                    if j==L
                        break
                    end
                    if Input(j)==Input(j+1)
                        comp=comp+1;
                    else
                        break
                    end
                end
                Output(k+1)=comp;
                Output(k)=Input(j);
                if j==L && Input(j-1)==Input(j)
                    break
                end
                i=i+1;
                k=k+2;
                j=j+1;
                if j==L
                    % if mod(L,2)==0
                    %     Output(k+1)=1;
                    %     Output(k)=Input(j);
                    % else
                    Output(k+1)=1;
                    Output(k)=Input(j);
                    % end
                    break
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.BackgroundColor = [0.49,0.18,0.56];
           [filename, pathname] = uigetfile({'*.jpg;*.png;*.tif', 'Image Files (*.jpg, *.png, *.tif)'}, 'Выберите изображение');
    if isequal(filename, 0) || isequal(pathname, 0)
        disp('Выбор файла отменен.');
    else
        fullpath3 = fullfile(pathname, filename);
        img = imread(fullpath3); 
        app.Image.ImageSource = img;
    end
            img = rgb2gray(img);
            img = img(:);
            a = rle(app,img);
            [file,path] = uiputfile('*.txt', 'Дайте имя файлу и укажите путь');
                        full_path = fullfile(path, file);
                        writematrix(a, full_path);
                        app.Button.BackgroundColor = [0.07,0.62,1.00];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Button_2.BackgroundColor = [0.49,0.18,0.56];
             [file,path] = uigetfile('*.txt', 'Дайте имя файлу и укажите путь');
                        full_path2 = fullfile(path, file);
                        fid = fopen(full_path2, 'r');
                       b = fgetl(fid); % Читаем одну строку из файла
                       delim = ',';
                vec = strsplit(b, delim);
                vec = irle(app,str2double(vec));
                        
                        vec = uint8(reshape(vec, [1080,1920]));
                        % app.Image2 = (vec);
                        fclose(fid); % Закрываем файл
                        figure;
                        imshow(vec);
app.Button_2.BackgroundColor = [0.07,0.62,1.00];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'k6.png');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'сжать';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'восстановить';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = k2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end